classdef WaterProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterCollectionProgramUIFigure  matlab.ui.Figure
        MoveBaseLeftButton              matlab.ui.control.Button
        RaiseStandButton                matlab.ui.control.Button
        SqueezePipetteButton            matlab.ui.control.Button
        MoveBaseRightButton             matlab.ui.control.Button
        LowerStandButton                matlab.ui.control.Button
        SeeThroughVisionSystemButton    matlab.ui.control.Button
        CurrentlyExecutingLabel         matlab.ui.control.Label
        ExecutingActionLabel            matlab.ui.control.Label
        StartAutomaticProcessButton     matlab.ui.control.Button
        ArduinoConnectionLampLabel      matlab.ui.control.Label
        ArduinoConnectionLamp           matlab.ui.control.Lamp
        ConnecttoArduinoButton          matlab.ui.control.Button
        EditVariablesButton             matlab.ui.control.Button
        EscButton                       matlab.ui.control.Button
    end

    properties (Access = public)
        
        a % arduino
        currentHeight % currentHeight of tip of pipette
        height % height to lower and raise stand (for manual testing only!)
        degrees % degrees to turn (for manual testing only!)
        stepsPerRevStepper % distance of stepper motor's rotation
        s % Serial Connection to Arduino Board
        port % name of port on Computer
        
    end
    
    methods (Access = public)
        
        function height = readHeight(app) % readHeight() returns height in (units)
            height = 15;
            fprintf("\nReading Height @ %d...", height);
        end
        
        function LowerStand(app, height) % LowerStand() lowers the tip the specified amount (height)
            fprintf("\nLowering Stand by %dcm...", height);
            
            pause(2);
            
            rotations = cmToRev(app, height, 0.5, app.stepsPerRevStepper);
            
            write(app.s, int2str(rotations),'string');
            
            app.currentHeight = app.currentHeight - height;
        end
        
        function RaiseStand(app, height) % RaiseStand() raises the tip the specified amount (height)
            fprintf("\nRaising Stand by %dcm...", height);
            
            pause(2);
            
            rotations = cmToRev(app, height, 0.5, app.stepsPerRevStepper);
            
            write(app.s, int2str(rotations),'string');
            
            app.currentHeight = app.currentHeight + height;
        end
        
        function SqueezePipette(app) % SqueezePipette() squeezes the pipette
            fprintf("\nSqueezing pipette...");
        end
        
        function RotateRight(app, degree) % Rotates the base right a specific amount (degree)
            fprintf("\nRotating %d degrees to the right...", degree);
            
            rotations = degreeToRev(app, degree, app.stepsPerRevStepper);
            
            write(app.s, int2str(rotations),'string');
        end
        
        function RotateLeft(app, degree) % Rotates the base right a specific amount (degree)
            fprintf("\nRotating %d degrees to the left...", degree);
            
            rotations = degreeToRev(app, degree, app.stepsPerRevStepper);
            
            write(app.s, int2str(rotations),'string');
        end
        
        function OpenCamera(app)
            fprintf("\nOpening Camera View...");
        end
        
        function StartAutomaticProcess(app)
            fprintf("\n\n\n-=(BEGIN AUTOMATIC PROCESSES)=-\n");
            RotateLeft(app, 90); % Rotate left 0.25 base rotation
            
            calcHeight = readHeight(app); % Recieve height of sample
            heightToMove = app.currentHeight - calcHeight; % Calculate how much needed to move
            
%             disp(heightToMove);
%             
%             if (heightToMove < 0)
%                 heightToMove = heightToMove * -1;
%             end
%             
%             disp(heightToMove);
            
            fprintf("\n");
            
            % Collect Sample
            fprintf("\nCollecting the Sample...");
            LowerStand(app, heightToMove);
            SqueezePipette(app);
            RaiseStand(app, heightToMove);
            
            fprintf("\n");
            
            % Go to deposit cup
            fprintf("\nGoing to deposit cup...");
            RotateRight(app, 180);
            
            fprintf("\n");
            
            % Deposit sample
            fprintf("\nDepositing the sample...")
            LowerStand(app, heightToMove);
            SqueezePipette(app);
            RaiseStand(app, heightToMove);
            
            fprintf("\n");
            
            % Return to neutral
            fprintf("\nResetting to neutral position...");
            RotateLeft(app, 90);
        end
        
        function setVars(app, port, distStepper, height, degrees)
            app.port = port;
            app.stepsPerRevStepper = distStepper;
            app.height = height;
            app.degrees = degrees;
            
            disp("Port set to: " + app.port);
            disp("Stepper Distance set to: " + app.stepsPerRevStepper);
            disp("Height set to: " + app.height);
            disp("Degrees set to: " + app.degrees);
        end
        
        function rev = cmToRev(app, cm, radius, steps)
            cm2in = cm/2.54;
            
            rev = (cm2in) * (steps / (2 * pi * radius)) * (1 / steps);
        end
        
        function rev = degreeToRev(app, degree, steps)
            degree2rev = steps / 360;
            
            rev = degree * degree2rev;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            disp("+---------------------+");
            disp("|Starting Application!|");
            disp("+---------------------+");
            disp("");


            app.ExecutingActionLabel.Text  = 'App Startup';
            app.currentHeight = 15;
            app.height = 0;
            app.degrees = 0;
            app.stepsPerRevStepper = 513;
            app.port = "/dev/cu.usbmodem1451101";
            
            drawnow
            
            app.ArduinoConnectionLamp.Color = [1 0 0];
            
            try
                app.a = arduino();
                app.ArduinoConnectionLamp.Color = [0 1 0];
            catch
                uialert(app.WaterCollectionProgramUIFigure, "Cannot connect to Arduino :/ Try again?", "Error!");
            end
            
            app.s = serialport(app.port, 9600);
            
            app.ExecutingActionLabel.Text = 'Started App';
            drawnow
        end

        % Button pushed function: LowerStandButton
        function LowerStandButtonPushed(app, event)
            LowerStand(app, app.height);
        end

        % Button pushed function: MoveBaseLeftButton
        function MoveBaseLeftButtonPushed(app, event)
            RotateLeft(app, app.degrees);
        end

        % Button pushed function: RaiseStandButton
        function RaiseStandButtonPushed(app, event)
            RaiseStand(app, app.height);
        end

        % Button pushed function: SqueezePipetteButton
        function SqueezePipetteButtonPushed(app, event)
            SqueezePipette(app);
        end

        % Button pushed function: MoveBaseRightButton
        function MoveBaseRightButtonPushed(app, event)
            RotateRight(app, app.degrees);
        end

        % Button pushed function: SeeThroughVisionSystemButton
        function SeeThroughVisionSystemButtonPushed(app, event)
            OpenCamera(app);
        end

        % Button pushed function: StartAutomaticProcessButton
        function StartAutomaticProcessButtonPushed(app, event)
            StartAutomaticProcess(app);
        end

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            app.ExecutingActionLabel.Text = 'Connecting to Arduino...';
            drawnow
            
            try
                app.a = arduino();
                app.ArduinoConnectionLamp.Color = [0 1 0];
                
                app.ExecutingActionLabel.Text = 'Connected to Arduino.';
                drawnow
            catch
                app.ExecutingActionLabel.Text = 'Could not connect to Arduino...';
                drawnow
                uialert(app.WaterCollectionProgramUIFigure, "Cannot connect to Arduino :/ Try again?", "Error!");
            end
            
            app.s = serialport(app.port, 9600);
        end

        % Button pushed function: EditVariablesButton
        function EditVariablesButtonPushed(app, event)
            app.var1 = 1;
            app.var2 = 2;
            app.var3 = 3;
            
            WaterProjectEditVariables(app);
        end

        % Button pushed function: EscButton
        function EscButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterCollectionProgramUIFigure = uifigure('Visible', 'off');
            app.WaterCollectionProgramUIFigure.Name = 'Water Collection Program';
            app.MoveBaseLeftButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.MoveBaseLeftButton.ButtonPushedFcn = createCallbackFcn(app, @MoveBaseLeftButtonPushed, true);
            app.MoveBaseLeftButton.Text = 'Move Base Left';
            app.RaiseStandButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.RaiseStandButton.ButtonPushedFcn = createCallbackFcn(app, @RaiseStandButtonPushed, true);
            app.RaiseStandButton.Text = 'Raise Stand';
            app.SqueezePipetteButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.SqueezePipetteButton.ButtonPushedFcn = createCallbackFcn(app, @SqueezePipetteButtonPushed, true);
            app.SqueezePipetteButton.Text = 'Squeeze Pipette';
            app.MoveBaseRightButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.MoveBaseRightButton.ButtonPushedFcn = createCallbackFcn(app, @MoveBaseRightButtonPushed, true);
            app.MoveBaseRightButton.Text = 'Move Base Right';
            app.LowerStandButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.LowerStandButton.ButtonPushedFcn = createCallbackFcn(app, @LowerStandButtonPushed, true);
            app.LowerStandButton.Text = 'Lower Stand';
            app.SeeThroughVisionSystemButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.SeeThroughVisionSystemButton.ButtonPushedFcn = createCallbackFcn(app, @SeeThroughVisionSystemButtonPushed, true);
            app.SeeThroughVisionSystemButton.Text = {'See Through'; 'Vision System'};
            app.CurrentlyExecutingLabel = uilabel(app.WaterCollectionProgramUIFigure);
            app.CurrentlyExecutingLabel.Text = 'Currently Executing:';
            app.ExecutingActionLabel = uilabel(app.WaterCollectionProgramUIFigure);
            app.ExecutingActionLabel.Text = '...';
            app.StartAutomaticProcessButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.StartAutomaticProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutomaticProcessButtonPushed, true);
            app.StartAutomaticProcessButton.Text = {'Start Automatic'; 'Process'};
            app.ArduinoConnectionLampLabel = uilabel(app.WaterCollectionProgramUIFigure);
            app.ArduinoConnectionLampLabel.Text = 'Arduino Connection';
            app.ArduinoConnectionLamp = uilamp(app.WaterCollectionProgramUIFigure);
            app.ArduinoConnectionLamp.Tag = 'connectionLamp';
            app.ConnecttoArduinoButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = {'Connect to'; 'Arduino'};
            app.EditVariablesButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.EditVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @EditVariablesButtonPushed, true);
            app.EditVariablesButton.Text = 'Edit Variables';
            app.EscButton = uibutton(app.WaterCollectionProgramUIFigure, 'push');
            app.EscButton.ButtonPushedFcn = createCallbackFcn(app, @EscButtonPushed, true);
            app.EscButton.Text = 'X';
            % Show the figure after all components are created
            app.WaterCollectionProgramUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaterProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaterCollectionProgramUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterCollectionProgramUIFigure)
        end
    end
end