classdef WaterProjectEditVariables < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StepperDistEditFieldLabel  matlab.ui.control.Label
        StepperDistEditField       matlab.ui.control.NumericEditField
        OKButton                   matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        HeightEditFieldLabel       matlab.ui.control.Label
        HeightEditField            matlab.ui.control.NumericEditField
        DegreesEditFieldLabel      matlab.ui.control.Label
        DegreesEditField           matlab.ui.control.NumericEditField
        PortEditFieldLabel         matlab.ui.control.Label
        PortEditField              matlab.ui.control.EditField
    end

    
    properties (Access = private)
        main % WaterProject Application Reference
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainGUI)
            app.main = mainGUI;
            
            disp(app.main.height);
            disp(app.main.degrees);
            disp(app.main.port);
            disp(app.main.stepsPerRevStepper);
            
            app.PortEditField.Value = app.main.port;
            app.StepperDistEditField.Value = app.main.stepsPerRevStepper;
            app.HeightEditField.Value = app.main.height;
            app.DegreesEditField.Value = app.main.degrees;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.main.setVars(app.PortEditField.Value, app.StepperDistEditField.Value, app.HeightEditField.Value, app.DegreesEditField.Value);
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
           delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StepperDistEditFieldLabel = uilabel(app.UIFigure);
            app.StepperDistEditFieldLabel.Text = 'Stepper Dist';
            app.StepperDistEditField = uieditfield(app.UIFigure, 'numeric');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DegreesEditFieldLabel = uilabel(app.UIFigure);
            app.DegreesEditFieldLabel.Text = 'Degrees';
            app.DegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaterProjectEditVariables(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end